classdef convolutorv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        GenerateSignal1Button       matlab.ui.control.Button
        GenerateSignal2Button       matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        SignalTypeDropDownLabel     matlab.ui.control.Label
        SignalTypeDropDown          matlab.ui.control.DropDown
        FunctionEditFieldLabel      matlab.ui.control.Label
        FunctionEditField           matlab.ui.control.EditField
        ShiftEditField              matlab.ui.control.NumericEditField
        ShiftEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        WidthEditField              matlab.ui.control.NumericEditField
        WidthEditFieldLabel         matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        Analysis                    matlab.ui.control.DropDown
        AnalysistypeLabel           matlab.ui.control.Label
        AnalyzeButton               matlab.ui.control.Button
        ShowinputfunctionsCheckBox  matlab.ui.control.CheckBox
        FUNCTIONANALYSISLabel       matlab.ui.control.Label
        FUNCTIONGENERATORLabel      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         function1;
         function2;
    end
    
    methods (Access = private)
        
        function f = function_generator(app)
             syms t x;
            
            if(strcmp(app.SignalTypeDropDown.Value,'Rectangular'))
                f(t)=app.AmplitudeEditField.Value*rectangularPulse((t-app.ShiftEditField.Value)/app.WidthEditField.Value);                
         
            elseif(strcmp(app.SignalTypeDropDown.Value,'Triangular'))
                f(t)=app.AmplitudeEditField.Value*triangularPulse((t-app.ShiftEditField.Value)/app.WidthEditField.Value);               
            
            elseif(strcmp(app.SignalTypeDropDown.Value,'Piecewise'))                
                f(t)=varpiecer(app.FunctionEditField.Value);
                
            elseif(strcmp(app.SignalTypeDropDown.Value,'Exponential'))
                f(t)=str2sym(app.FunctionEditField.Value);
                
            elseif(strcmp(app.SignalTypeDropDown.Value,'Custom'))
                f(t)=str2sym(app.FunctionEditField.Value);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FunctionEditField.Value='Function customization field';
            app.AmplitudeEditField.Value=1;
            app.ShiftEditField.Value=0;
            app.WidthEditField.Value=1;
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            
            if(strcmp(app.SignalTypeDropDown.Value,'Rectangular'))
                app.FunctionEditField.Value='EDIT PARAMETERS IN THE BOXES BELOW';            
           
            elseif(strcmp(app.SignalTypeDropDown.Value,'Triangular'))
                app.FunctionEditField.Value='EDIT PARAMETERS IN THE BOXES BELOW';            
            
            elseif(strcmp(app.SignalTypeDropDown.Value,'Piecewise'))
                app.FunctionEditField.Value="={ t<-1 ; 0 } { t=-1 ; 0 } {-1<t<0 ; t+1 } { t=0 ; 1 }  { 0<t<1 ; 1 } { t>1 ; 0}";
            
            elseif(strcmp(app.SignalTypeDropDown.Value,'Exponential'))
                app.FunctionEditField.Value="exp(-t)*heaviside(t)";
                
            elseif(strcmp(app.SignalTypeDropDown.Value,'Custom'))
                app.FunctionEditField.Value="Enter your function here % Use 't' as variable";
            end
                     
        end

        % Button pushed function: GenerateSignal1Button
        function GenerateSignal1ButtonPushed(app, event)
            app.function1=function_generator(app);
            fplot(app.UIAxes,app.function1);            
        end

        % Button pushed function: GenerateSignal2Button
        function GenerateSignal2ButtonPushed(app, event)
            app.function2=function_generator(app);
            fplot(app.UIAxes2,app.function2);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            disp(app.SignalTypeDropDown.Value)
            
            if strcmp(app.Analysis.Value,'Convolution')
                con=convo(app.function1,app.function2);
                fplot(app.UIAxes3,con);
                hold(app.UIAxes3,"on");
                
            elseif strcmp(app.Analysis.Value,'Fourier')
                fouri(app.UIAxes3,app.function1);
            else
                disp('No match in signal type');
            end
        end

        % Value changed function: ShowinputfunctionsCheckBox
        function ShowinputfunctionsCheckBoxValueChanged(app, event)
        if app.ShowinputfunctionsCheckBox.Value
            fplot(app.UIAxes3,app.function1,'r');             
            fplot(app.UIAxes3,app.function2,'g');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GenerateSignal1Button = uibutton(app.UIFigure, 'push');
            app.GenerateSignal1Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignal1ButtonPushed, true);
            app.GenerateSignal1Button.Text = 'Generate Signal (1)';
            app.GenerateSignal2Button = uibutton(app.UIFigure, 'push');
            app.GenerateSignal2Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignal2ButtonPushed, true);
            app.GenerateSignal2Button.Text = 'Generate Signal (2)';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.SignalTypeDropDownLabel = uilabel(app.Panel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.Panel);
            app.SignalTypeDropDown.Items = {'Select', 'Exponential', 'Rectangular', 'Piecewise', 'Triangular', 'Custom'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Select';
            app.FunctionEditFieldLabel = uilabel(app.Panel);
            app.FunctionEditFieldLabel.Text = 'Function ';
            app.FunctionEditField = uieditfield(app.Panel, 'text');
            app.ShiftEditField = uieditfield(app.Panel, 'numeric');
            app.ShiftEditFieldLabel = uilabel(app.Panel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.AmplitudeEditFieldLabel = uilabel(app.Panel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Panel, 'numeric');
            app.WidthEditField = uieditfield(app.Panel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.Panel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Analysis = uidropdown(app.Panel_2);
            app.Analysis.Items = {'Convolution', 'Fourier'};
            app.Analysis.Value = 'Convolution';
            app.AnalysistypeLabel = uilabel(app.Panel_2);
            app.AnalysistypeLabel.Text = 'Analysis type';
            app.AnalyzeButton = uibutton(app.Panel_2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ShowinputfunctionsCheckBox = uicheckbox(app.Panel_2);
            app.ShowinputfunctionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowinputfunctionsCheckBoxValueChanged, true);
            app.ShowinputfunctionsCheckBox.Text = 'Show input functions';
            app.FUNCTIONANALYSISLabel = uilabel(app.UIFigure);
            app.FUNCTIONANALYSISLabel.Text = '                    FUNCTION ANALYSIS';
            app.FUNCTIONGENERATORLabel = uilabel(app.UIFigure);
            app.FUNCTIONGENERATORLabel.Text = '                  FUNCTION GENERATOR';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolutorv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end